classdef Solver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        PlotTab_2             matlab.ui.container.Tab
        Panel                 matlab.ui.container.Panel
        ParametersPanel       matlab.ui.container.Panel
        DEditFieldLabel       matlab.ui.control.Label
        DEditField            matlab.ui.control.NumericEditField
        betaEditFieldLabel    matlab.ui.control.Label
        betaEditField         matlab.ui.control.NumericEditField
        CpEditFieldLabel      matlab.ui.control.Label
        CpEditField           matlab.ui.control.NumericEditField
        PostionPanel          matlab.ui.container.Panel
        leftXEditFieldLabel   matlab.ui.control.Label
        leftXEditField        matlab.ui.control.NumericEditField
        rightXEditFieldLabel  matlab.ui.control.Label
        rightXEditField       matlab.ui.control.NumericEditField
        numXEditFieldLabel    matlab.ui.control.Label
        numXEditField         matlab.ui.control.NumericEditField
        PulsesPanel           matlab.ui.container.Panel
        PulsePanel            matlab.ui.container.Panel
        TimeEditFieldLabel    matlab.ui.control.Label
        TimeEditField         matlab.ui.control.NumericEditField
        CarburizationButton   matlab.ui.control.Button
        DiffusionButton       matlab.ui.control.Button
        UITable2              matlab.ui.control.Table
        ClearButton           matlab.ui.control.Button
        CalculateButton       matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        DataTab               matlab.ui.container.Tab
        UITable               matlab.ui.control.Table
    end


    methods (Access = private)
    
        function C = solver(app, leftX, rightX, numX, initialT, endT, numT, D, beta, Cp, C0)
            %  solver函数利用Crank-Nicolson有限差分格式求解扩散方程.
            %  函数返回值C为numT*numX的二维矩阵,用于存储各位置各时间的碳浓度值.
            %  形参leftX,rightX,numX均为标量,分别表示左边界位置,右边界位置和位置轴格点数量.
            %  形参initialT,endT,numT均为标量,分别表示初始时间,终止时间和时间轴格点数量.
            %  形参D,beta,Cp均为标量,分别表示扩散系数,传递系数和碳势.
            %  形参C0为1*numX的向量,表示各位置处的初始碳浓度值.


            deltaX = (rightX - leftX) / (numX - 1);
            deltaT = (endT - initialT) / (numT - 1);


            C = zeros(numT, numX);
            C(1,:) = C0;


            % --------------------未考虑初始条件和边界条件的系数矩阵-----------------------
            r = 1 / 2 * D * deltaT / deltaX^2;
            e = ones(numX,1);
            S = spdiags([e -2*e e], -1:1, numX, numX);
            A = eye(numX) - r * S;
            B = eye(numX) + r * S;
            % --------------------未考虑初始条件和边界条件的系数矩阵-----------------------


            % --------------------------第三类边界条件-----------------------------------
            b = zeros(numX,1);

            % ----------------------------左边界条件------------------------------------
            A(1,1) = 2 * deltaX^2 / D / deltaT + 2 * beta * deltaX / D + 2;
            A(1,2) = -2;
            B(1,1) = 2 * deltaX^2 / D / deltaT - 2 * beta * deltaX / D - 2;
            B(1,2) = 2;
            b(1) = 4 * beta * deltaX * Cp / D;
            % ----------------------------左边界条件------------------------------------

            % ----------------------------右边界条件------------------------------------
            A(end,end) = 1;
            A(end,end-1) = 0;
            B(end,end) = 0;
            B(end,end-1) = 0;
            b(end) = 0.0013; % 0.0013为M50NiL钢初始碳浓度
            % ----------------------------右边界条件------------------------------------

            % --------------------------第三类边界条件-----------------------------------


            % -------------------------碳浓度C矩阵求解-----------------------------------
            for n = 1:numT-1
                C(n+1,:) = (A \ (B * C(n,:)' + b))';
            end
            % -------------------------碳浓度C矩阵求解-----------------------------------

        end
        
    end


    methods (Access = private)

        % Button pushed function: CarburizationButton
        function CarburizationButtonPushed(app, event)
            
            t = app.TimeEditField.Value;
            tableData = app.UITable2.Data;
            if ~isempty(tableData)
                pulse = tableData.pulse;
                time = tableData.time;
                len = length(pulse);
                pulse(len+1,1) = {'Carburization'};
                time = [time;t];
                tableData = table(pulse, time);
                app.UITable2.Data = tableData;
            else
                pulse = {'Carburization'};
                time = t;
                tableData = table(pulse, time);
                app.UITable2.Data = tableData;
            end            
        end

        % Button pushed function: DiffusionButton
        function DiffusionButtonPushed(app, event)
            t = app.TimeEditField.Value;
            tableData = app.UITable2.Data;
            if ~isempty(tableData)
                pulse = tableData.pulse;
                time = tableData.time;
                len = length(pulse);
                pulse(len+1,1) = {'Diffusion'};
                time = [time;t];
                tableData = table(pulse, time);
                app.UITable2.Data = tableData;
            end            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)

            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % ---------------------------用户需给出以下参数的值---------------------------
            leftX = app.leftXEditField.Value; % leftX必须为0,因为left=0对应方程的左边界条件
            rightX = app.rightXEditField.Value; % rightX的值需满足右边界条件,建议rightX取较大的值
            numX = app.numXEditField.Value; % 划分的格点数没有特殊要求,因为Crank-Nicolson方法是稳定的,numX的值合适就好

            pulses = app.UITable2.Data.time; % 每个强渗脉冲或扩散脉冲持续的时间,此数组应以强渗脉冲开始
            pulses = pulses';

            D = app.DEditField.Value; % 扩散系数
            beta = app.betaEditField.Value; % 传递系数
            Cp = app.CpEditField.Value; % 气氛碳势
            % ---------------------------用户需给出以上参数的值---------------------------


            % -------------------------强渗脉冲或扩散脉冲--------------------------------
            len = length(pulses);
            pulse = struct('initialT', 0,'endT', 0, 'numT', 1000);
            pulse = repmat(pulse, 1, len);
            for m = 1:len
                pulse(m).endT = pulses(m);
            end
            % -------------------------强渗脉冲或扩散脉冲--------------------------------


            x = linspace(leftX, rightX, numX);

            C0 = icfun(x);
            %C0 = exactSol(Cp, 0.0013, D, beta, x, 30);


            % -----------------------------碳浓度C矩阵----------------------------------
            for n = 1:len
                if pulse(n).endT ~= 0
                    if mod(n,2) == 0
                        C = solver(app, leftX, rightX,numX, pulse(n).initialT, pulse(n).endT, pulse(n).numT, D, 0, Cp, C0);
                    else
                        C = solver(app, leftX, rightX, numX, pulse(n).initialT, pulse(n).endT, pulse(n).numT, D, beta, Cp, C0);
                    end
                    C0 = C(end, :);
                end
            end
            % -----------------------------碳浓度C矩阵----------------------------------


            sol = exactSol(Cp, 0.0013, D, beta, x, 30);


            plot(app.UIAxes,x, C(end, :), '*', x, sol);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlotTab_2 = uitab(app.TabGroup);
            app.PlotTab_2.Title = 'Plot';
            app.Panel = uipanel(app.PlotTab_2);
            app.ParametersPanel = uipanel(app.Panel);
            app.ParametersPanel.Title = 'Parameters';
            app.DEditFieldLabel = uilabel(app.ParametersPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.betaEditFieldLabel = uilabel(app.ParametersPanel);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.CpEditFieldLabel = uilabel(app.ParametersPanel);
            app.CpEditFieldLabel.Text = 'Cp';
            app.CpEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.PostionPanel = uipanel(app.Panel);
            app.PostionPanel.Title = 'Postion';
            app.leftXEditFieldLabel = uilabel(app.PostionPanel);
            app.leftXEditFieldLabel.Text = 'leftX';
            app.leftXEditField = uieditfield(app.PostionPanel, 'numeric');
            app.rightXEditFieldLabel = uilabel(app.PostionPanel);
            app.rightXEditFieldLabel.Text = 'rightX';
            app.rightXEditField = uieditfield(app.PostionPanel, 'numeric');
            app.numXEditFieldLabel = uilabel(app.PostionPanel);
            app.numXEditFieldLabel.Text = 'numX';
            app.numXEditField = uieditfield(app.PostionPanel, 'numeric');
            app.PulsesPanel = uipanel(app.Panel);
            app.PulsesPanel.Title = 'Pulses';
            app.PulsePanel = uipanel(app.PulsesPanel);
            app.PulsePanel.Title = 'Pulse';
            app.TimeEditFieldLabel = uilabel(app.PulsePanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.PulsePanel, 'numeric');
            app.CarburizationButton = uibutton(app.PulsesPanel, 'push');
            app.CarburizationButton.ButtonPushedFcn = createCallbackFcn(app, @CarburizationButtonPushed, true);
            app.CarburizationButton.Text = 'Carburization';
            app.DiffusionButton = uibutton(app.PulsesPanel, 'push');
            app.DiffusionButton.ButtonPushedFcn = createCallbackFcn(app, @DiffusionButtonPushed, true);
            app.DiffusionButton.Text = 'Diffusion';
            app.UITable2 = uitable(app.PulsesPanel);
            app.UITable2.ColumnName = {'Pulse'; 'Time'};
            app.UITable2.RowName = {};
            app.ClearButton = uibutton(app.PulsesPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UIAxes = uiaxes(app.PlotTab_2);
            title(app.UIAxes, 'Data Visualization')
            xlabel(app.UIAxes, 'Depth (m)')
            ylabel(app.UIAxes, 'Carbon Content (wt.%)')
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Solver

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end